classdef Fitset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FitsetMain                   matlab.ui.Figure
        CoTable                      matlab.ui.control.Table
        DoneButton                   matlab.ui.control.Button
        EquationPanel                matlab.ui.container.Panel
        zfxyTextAreaLabel            matlab.ui.control.Label
        Equation                     matlab.ui.control.TextArea
        EquationDone                 matlab.ui.control.Button
        FitOptionPanel               matlab.ui.container.Panel
        MaxFunEvalsEditFieldLabel    matlab.ui.control.Label
        MaxFunEvals                  matlab.ui.control.NumericEditField
        DiffMaxChangeEditFieldLabel  matlab.ui.control.Label
        DiffMaxChange                matlab.ui.control.NumericEditField
        DiffMinChangeEditFieldLabel  matlab.ui.control.Label
        DiffMinChange                matlab.ui.control.NumericEditField
        MaxIterEditFieldLabel        matlab.ui.control.Label
        MaxIter                      matlab.ui.control.NumericEditField
        TolFunEditFieldLabel         matlab.ui.control.Label
        TolFun                       matlab.ui.control.NumericEditField
        TolXEditFieldLabel           matlab.ui.control.Label
        TolX                         matlab.ui.control.NumericEditField
        RobustDropDownLabel          matlab.ui.control.Label
        Robust                       matlab.ui.control.DropDown
        AlgorithmDropDownLabel       matlab.ui.control.Label
        Algorithm                    matlab.ui.control.DropDown
        ApplyButton                  matlab.ui.control.Button
    end


    properties (Access = public)
        vuima % Description
        
    end

    methods (Access = public)
        function opts = setfitoptions(~,Algorithm, DiffMaxChange,...
                DiffMinChange, Upper, Lower, StartPoint, MaxFunEvals, MaxIter, Robust, TolFun, TolX)
            
            opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
            opts.Algorithm = Algorithm;
            opts.DiffMaxChange = DiffMaxChange;
            opts.DiffMinChange = DiffMinChange;
            opts.Upper = Upper;
            opts.Lower = Lower;
            opts.StartPoint = StartPoint;
            opts.MaxFunEvals = MaxFunEvals;
            opts.MaxIter = MaxIter;
            opts.Robust = Robust;
            opts.TolFun = TolFun;
            opts.TolX = TolX;
            opts.Display = 'Notify';
            
        end
        
        
    end

    methods (Access = private)
    
        function results = ApplyChange(app)
            Vuima = app.vuima;
            Vuima.opt = setfitoptions(app,...
                app.Algorithm.Value,...
                app.DiffMaxChange.Value,...
                app.DiffMinChange.Value,...
                cell2mat(app.CoTable.Data(:,4)),... % Upper
                cell2mat(app.CoTable.Data(:,3)),... %Lower
                cell2mat(app.CoTable.Data(:,2)),... %StartPoint
                app.MaxFunEvals.Value,...
                app.MaxIter.Value,...
                app.Robust.Value,...
                app.TolFun.Value,...
                app.TolX.Value...
                );
            Vuima.Equation = app.Equation.Value;
        end
        
    end


    methods (Access = private)

        % Button pushed function: EquationDone
        function EquationDoneButtonPushed(app, event)
            %get variable in equation
            if ~isempty(app.Equation.Value{1})
                coefficients = symvar(app.Equation.Value{1});
                coefficients = coefficients(~strcmp(coefficients,'x'));
                coefficients = coefficients(~strcmp(coefficients,'y'));
                %create coTable
                StartPoint = num2cell(zeros(length(coefficients),1));
                Lower = num2cell(-inf(length(coefficients),1));
                Upper = num2cell(inf(length(coefficients),1));
                %num2cell(true(7,1));
                app.CoTable.Enable = 'on';
                app.CoTable.Data = [coefficients StartPoint Lower Upper];
            end
            
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            ApplyChange(app);
            delete(app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            ApplyChange(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FitsetMain = uifigure;
            app.FitsetMain.Name = 'Fitset';
            app.CoTable = uitable(app.FitsetMain);
            app.CoTable.ColumnName = {'Coefficients'; 'StartPoint'; 'Lower'; 'Upper'};
            app.CoTable.RowName = {};
            app.CoTable.ColumnEditable = true;
            app.CoTable.Enable = 'off';
            app.DoneButton = uibutton(app.FitsetMain, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.EquationPanel = uipanel(app.FitsetMain);
            app.EquationPanel.Title = 'Equation';
            app.zfxyTextAreaLabel = uilabel(app.EquationPanel);
            app.zfxyTextAreaLabel.Text = 'z = f ( x, y ) =';
            app.Equation = uitextarea(app.EquationPanel);
            app.Equation.Value = {'bg + peak * exp( - 0.5* (x-xc)^2 / sigx^2 -0.5* (y-yc)^2/sigy^2) '};
            app.EquationDone = uibutton(app.EquationPanel, 'push');
            app.EquationDone.ButtonPushedFcn = createCallbackFcn(app, @EquationDoneButtonPushed, true);
            app.EquationDone.Text = 'Done';
            app.FitOptionPanel = uipanel(app.FitsetMain);
            app.FitOptionPanel.Title = 'Fit Option';
            app.MaxFunEvalsEditFieldLabel = uilabel(app.FitOptionPanel);
            app.MaxFunEvalsEditFieldLabel.Text = 'MaxFunEvals';
            app.MaxFunEvals = uieditfield(app.FitOptionPanel, 'numeric');
            app.MaxFunEvals.Value = 600;
            app.DiffMaxChangeEditFieldLabel = uilabel(app.FitOptionPanel);
            app.DiffMaxChangeEditFieldLabel.Text = 'DiffMaxChange';
            app.DiffMaxChange = uieditfield(app.FitOptionPanel, 'numeric');
            app.DiffMaxChange.Value = 0.1;
            app.DiffMinChangeEditFieldLabel = uilabel(app.FitOptionPanel);
            app.DiffMinChangeEditFieldLabel.Text = 'DiffMinChange';
            app.DiffMinChange = uieditfield(app.FitOptionPanel, 'numeric');
            app.DiffMinChange.Value = 1e-08;
            app.MaxIterEditFieldLabel = uilabel(app.FitOptionPanel);
            app.MaxIterEditFieldLabel.Text = 'MaxIter';
            app.MaxIter = uieditfield(app.FitOptionPanel, 'numeric');
            app.MaxIter.Value = 400;
            app.TolFunEditFieldLabel = uilabel(app.FitOptionPanel);
            app.TolFunEditFieldLabel.Text = 'TolFun';
            app.TolFun = uieditfield(app.FitOptionPanel, 'numeric');
            app.TolFun.Value = 1e-06;
            app.TolXEditFieldLabel = uilabel(app.FitOptionPanel);
            app.TolXEditFieldLabel.Text = 'TolX';
            app.TolX = uieditfield(app.FitOptionPanel, 'numeric');
            app.TolX.Value = 1e-06;
            app.RobustDropDownLabel = uilabel(app.FitOptionPanel);
            app.RobustDropDownLabel.Text = 'Robust';
            app.Robust = uidropdown(app.FitOptionPanel);
            app.Robust.Items = {'Off', 'LAR', 'Bisquare'};
            app.Robust.Value = 'Off';
            app.AlgorithmDropDownLabel = uilabel(app.FitOptionPanel);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.Algorithm = uidropdown(app.FitOptionPanel);
            app.Algorithm.Items = {'Trust-Region', 'Levenberg-Marquardt'};
            app.Algorithm.Value = 'Trust-Region';
            app.ApplyButton = uibutton(app.FitsetMain, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Fitset

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FitsetMain)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FitsetMain)
        end
    end
end